classdef ExplainabilityDashboard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ExplainabilityDashboardPanel  matlab.ui.container.Panel
        LoadSampleButton              matlab.ui.control.Button
        LesionCandidatesPanel         matlab.ui.container.Panel
        LesionAxes                    matlab.ui.control.UIAxes
        VesselSegmentationPanel       matlab.ui.container.Panel
        VesselAxes                    matlab.ui.control.UIAxes
        GradCAMVisualizationPanel     matlab.ui.container.Panel
        GradCAMAxes                   matlab.ui.control.UIAxes
        OriginalFundusImagePanel      matlab.ui.container.Panel
        OriginalAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadSampleButton
        function LoadSampleButtonPushed(app, event)
            img = imread('D:\SIH\aptos2019-blindness-detection\test_images\0005cfc8afb6.png');

            imshow(img, 'Parent', app.OriginalAxes);
            imshow(img, 'Parent', app.GradCAMAxes);
            imshow(img, 'Parent', app.VesselAxes);
            imshow(img, 'Parent', app.LesionAxes);  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ExplainabilityDashboardPanel = uipanel(app.UIFigure);
            app.ExplainabilityDashboardPanel.Title = 'Explainability Dashboard';
            app.OriginalFundusImagePanel = uipanel(app.ExplainabilityDashboardPanel);
            app.OriginalFundusImagePanel.Title = 'Original Fundus Image';
            app.OriginalAxes = uiaxes(app.OriginalFundusImagePanel);
            title(app.OriginalAxes, 'Title')
            xlabel(app.OriginalAxes, 'X')
            ylabel(app.OriginalAxes, 'Y')
            zlabel(app.OriginalAxes, 'Z')
            app.GradCAMVisualizationPanel = uipanel(app.ExplainabilityDashboardPanel);
            app.GradCAMVisualizationPanel.Title = 'Grad-CAM Visualization';
            app.GradCAMAxes = uiaxes(app.GradCAMVisualizationPanel);
            title(app.GradCAMAxes, 'Title')
            xlabel(app.GradCAMAxes, 'X')
            ylabel(app.GradCAMAxes, 'Y')
            zlabel(app.GradCAMAxes, 'Z')
            app.VesselSegmentationPanel = uipanel(app.ExplainabilityDashboardPanel);
            app.VesselSegmentationPanel.Title = 'Vessel Segmentation';
            app.VesselAxes = uiaxes(app.VesselSegmentationPanel);
            title(app.VesselAxes, 'Title')
            xlabel(app.VesselAxes, 'X')
            ylabel(app.VesselAxes, 'Y')
            zlabel(app.VesselAxes, 'Z')
            app.LesionCandidatesPanel = uipanel(app.ExplainabilityDashboardPanel);
            app.LesionCandidatesPanel.Title = 'Lesion Candidates';
            app.LesionAxes = uiaxes(app.LesionCandidatesPanel);
            title(app.LesionAxes, 'Title')
            xlabel(app.LesionAxes, 'X')
            ylabel(app.LesionAxes, 'Y')
            zlabel(app.LesionAxes, 'Z')
            app.LoadSampleButton = uibutton(app.ExplainabilityDashboardPanel, 'push');
            app.LoadSampleButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSampleButtonPushed, true);
            app.LoadSampleButton.Text = 'Load Sample Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExplainabilityDashboard

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end